classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        WywietlaniePanel               matlab.ui.container.Panel
        SkalaOXButtonGroup             matlab.ui.container.ButtonGroup
        CzassHzButton                  matlab.ui.control.ToggleButton
        PrbkanButton                   matlab.ui.control.ToggleButton
        PloterButtonGroup              matlab.ui.container.ButtonGroup
        stemButton                     matlab.ui.control.ToggleButton
        plotButton                     matlab.ui.control.ToggleButton
        SkalaOYButtonGroup             matlab.ui.container.ButtonGroup
        LogdBButton                    matlab.ui.control.ToggleButton
        LinButton                      matlab.ui.control.ToggleButton
        ParametryDFTPanel              matlab.ui.container.Panel
        InterpolacjazeramiCheckBox     matlab.ui.control.CheckBox
        DodatkoweparametryoknaEditField  matlab.ui.control.EditField
        DodatkoweparametryoknaEditFieldLabel  matlab.ui.control.Label
        TypoknaDropDown                matlab.ui.control.DropDown
        TypoknaDropDownLabel           matlab.ui.control.Label
        PrbkiinterpolacjiSpinner       matlab.ui.control.Spinner
        PrbkiinterpolacjiSpinnerLabel  matlab.ui.control.Label
        ParametrysygnauPanel           matlab.ui.container.Panel
        fpEditField                    matlab.ui.control.NumericEditField
        fpEditFieldLabel               matlab.ui.control.Label
        kSpinner                       matlab.ui.control.Spinner
        kSpinnerLabel                  matlab.ui.control.Label
        NSpinner                       matlab.ui.control.Spinner
        NSpinnerLabel                  matlab.ui.control.Label
        PhiEditField                   matlab.ui.control.NumericEditField
        PhiEditFieldLabel              matlab.ui.control.Label
        AEditField                     matlab.ui.control.NumericEditField
        AEditFieldLabel                matlab.ui.control.Label
        TypsygnauButtonGroup           matlab.ui.container.ButtonGroup
        FormulaEditField               matlab.ui.control.EditField
        FormulaEditFieldLabel          matlab.ui.control.Label
        CustomButton                   matlab.ui.control.ToggleButton
        expButton                      matlab.ui.control.ToggleButton
        cosButton                      matlab.ui.control.ToggleButton
        sinButton                      matlab.ui.control.ToggleButton
        UIAxes                         matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        PorwnanieokienczasowychPanel   matlab.ui.container.Panel
        PorwnajsygnadlawybranychokienczasowychButton  matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        CzrzeczywistaurojonaTab        matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        WidmoamplitudowefazoweTab      matlab.ui.container.Tab
        UIAxes3_2                      matlab.ui.control.UIAxes
        UIAxes2_2                      matlab.ui.control.UIAxes
        PaszczyznaZespolonaTab         matlab.ui.container.Tab
        PaszczyznazespolonadlaprkwDFTPanel  matlab.ui.container.Panel
        UIAxes2_3                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    %Andrzej Małolepszy, 253206
    properties (Access = public)
        %----------------------
        % parametry sygnału
        %----------------------
        A   %amplituda
        N   %ilośc próbek
        k   %ilość okresów
        phi %faza
        fp  %czest. probkowania
        n   %wektor próbek

        %---------------------
        % parametry DFT
        %---------------------
        n_interpol      %probki interpolacji zerami
        window_type     %typ okna
        window_params   %dodatkowe parametry
        window          %funkcja okna
        NPSG            %współczynnik dzielenia dft
        
        %--------------------
        % opcje wyświetlania
        %--------------------
        OY_type     %typ wyswietlania osi Y (dB/lin)
        OX_type     %typ wyswietlania osi X (probki, czas)
        plot_type   %plot() albo stem()

        %zmienne z wartosciami sygnalow
        y           %sygnal badany
        sig_type    %typ sygnalu sin, cos, exp
        DFT         %widmo sygnalu
    
    end
    
    methods (Access = public)

        %---- metody update'ujace zmienne z parametrami sygnalu
        %A
        function [] = update_A(app, A)
            app.A = A;
        end
        %N
        function [] = update_N(app, N)
            app.N = N;
            %ustaw n_interpol do nextpow2
            app.n_interpol = 2^nextpow2(N);
            app.PrbkiinterpolacjiSpinner.Value = app.n_interpol;
        end
        %k
        function [] = update_k(app, k)
            app.k = k;
        end
        %phi
        function [] = update_phi(app, phi)
            app.phi = phi;
        end
        %fp
        function [] = update_fp(app, fp)
            app.fp = fp;
        end
        
        %-------- zmienne z parametrami DFT
        %n_interpol
        function [] = update_n_interpol(app, n_interpol)
            %ustaw n_interpol do nextpow2
            app.n_interpol = 2^nextpow2(n_interpol);
            if(app.n_interpol < app.N)
                app.n_interpol = app.N;
            end
            app.PrbkiinterpolacjiSpinner.Value = app.n_interpol;
        end

        %window_type
        function [] = update_window_type(app, window_type)
            %ilość próbek okna
            %jesli uzupelnianie zerami

            %okno prostokątne
            if(isequal(window_type, '1'))
                app.window = ones(1, app.N);
                %dezaktywuj edit field gdy nieparametryczne
                set(app.DodatkoweparametryoknaEditField, 'enable', 'off');
                set(app.DodatkoweparametryoknaEditFieldLabel, 'enable', 'off');
            end
            %okna nieparametryczne
            if(isequal(window_type, '@triang') || isequal(window_type, '@hann') || ...
                    isequal(window_type, '@hamming') || isequal(window_type, '@blackman'))
                %dezaktywuj edit field gdy nieparametryczne
                set(app.DodatkoweparametryoknaEditField, 'enable', 'off');
                set(app.DodatkoweparametryoknaEditFieldLabel, 'enable', 'off');
                %generuj funkcje okna
                app.window_type = eval(window_type);
                app.window = (app.window_type(app.N))';
            end
            %-------------okna parametryczne-------------------------
            %okno sin^p
            if(isequal(window_type, 'sinp'))
                %aktywuj edit field gdy parametryczne
                set(app.DodatkoweparametryoknaEditField, 'enable', 'on');
                set(app.DodatkoweparametryoknaEditFieldLabel, 'enable', 'on');

                app.window = sin(1 : app.N).^ app.window_params;
            end
            %okno kaisera, chebyszew-dolph
            if((isequal(window_type, '@kaiser')) || (isequal(window_type, '@chebwin')))
                %aktywuj edit field gdy parametryczne
                set(app.DodatkoweparametryoknaEditField, 'enable', 'on');
                set(app.DodatkoweparametryoknaEditFieldLabel, 'enable', 'on');

                %generuj funkcje okna
                app.window_type = eval(window_type);
                app.window = (app.window_type(app.N, app.window_params))';
            end
            
        end

        %window_params
        function [] = update_window_params(app, window_params)
            app.window_params = double(window_params);
        end
        
        %----------zmienne z parametrami wyswietlania
        %plot_type
        function [] = update_plot_type(app, plot_type)
            app.plot_type = eval(strcat('@', plot_type));
        end

        %typ osi Y - lin,log
        function [] = update_OY_type(app, OY_type)
            app.OY_type = OY_type;
            
            %nadanie osiom Y labelów
            if (OY_type == "log")
                app.UIAxes2.YLabel.String = "Amplituda [dB]";
                app.UIAxes3.YLabel.String = "Amplituda [dB]";
                app.UIAxes2_2.YLabel.String = "Amplituda [dB]";
            else
                app.UIAxes2.YLabel.String = "Amplituda";
                app.UIAxes3.YLabel.String = "Amplituda";
                app.UIAxes2_2.YLabel.String = "Amplituda";
            end
                
        end

        %typ osi X - próbla, czas
        function [] = update_OX_type(app, OX_type)

            %nadanie osi X labela
            app.UIAxes.XLabel.String = OX_type;
            app.UIAxes.XLabel.String(1) = upper(app.UIAxes.XLabel.String(1));
            if (OX_type == "czas")
                app.UIAxes.XLabel.String = strcat(app.UIAxes.XLabel.String, " [s]");
            else
                app.UIAxes.XLabel.String = strcat(app.UIAxes.XLabel.String, " [n]");
            end

            %Hz
            if (OX_type == "czas")
                app.UIAxes2.XLabel.String = "Częstotliwość [Hz]";
                app.UIAxes3.XLabel.String = "Częstotliwość [Hz]";
                app.UIAxes2_2.XLabel.String = "Częstotliwość [Hz]";
            else
                app.UIAxes2.XLabel.String = "Próbka [n]";
                app.UIAxes3.XLabel.String = "Próbka [n]";
                app.UIAxes2_2.XLabel.String = "Próbka [n]";
            end
        end

        %wartosci z buttonow
        function [] = update_signal_type(app, selected_button)
            if (selected_button == "Custom:")
                %aktywuj formula edit field
                set(app.FormulaEditField, 'editable', 'on');
                set(app.FormulaEditField, 'enable', 'on');
                set(app.FormulaEditFieldLabel, 'enable', 'on');                

                %wyszarz parametry
                %wyszarz N
                set(app.NSpinner, 'enable', 'off');
                %wyszarz A
                set(app.AEditField, 'enable', 'off');
                %wyszarz phi
                set(app.PhiEditField, 'enable', 'off');
                %wyszarz k
                set(app.kSpinner, 'enable', 'off');

            else
                app.sig_type = eval(strcat('@', selected_button));

                %dezaktywuj formula edit field
                set(app.FormulaEditField, 'editable', 'off');
                set(app.FormulaEditField, 'enable', 'off');
                set(app.FormulaEditFieldLabel, 'enable', 'off');   

                %odszarz parametry
                %odszarz N
                set(app.NSpinner, 'enable', 'on');
                %odszarz A
                set(app.AEditField, 'enable', 'on');
                %odszarz phi
                set(app.PhiEditField, 'enable', 'on');
                %odszarz k
                set(app.kSpinner, 'enable', 'on');
            end
        end

        %wartosc z custom fielda
        function [] = update_custom_signal_type(app, custom_sig)
            app.y = evalin('base', custom_sig);

            %sprawdz czy kolumna
            [kolumny wiersze] = size(app.y);
            if(kolumny > 1)
                %jeśli kolumna to transponuj
                app.y = app.y';
            end
            %długość sygnału
            app.N = length(app.y);
        end


        %------------------------------------------------
        % REFRESH, PLOT
        %------------------------------------------------
        function [] = refresh(app)
            %argument exp() to 1j
            arg_real = 1;
            if(app.TypsygnauButtonGroup.SelectedObject.Text == "exp")
                arg_real = 1j;
            end
            
            %sprawdź custom val
            if(app.TypsygnauButtonGroup.SelectedObject.Text == "Custom:")
                %skip generowanie sygnalu
                app.update_custom_signal_type(app.FormulaEditField.Value);

                %okienkowanie
                update_window_type(app, app.TypoknaDropDown.Value);
                app.y = app.y.* app.window;


                %metoda interpolacji zerami
                if(app.InterpolacjazeramiCheckBox.Value == true)
                    app.n = 0 : (app.n_interpol - 1);
                    tmp = app.n_interpol - app.N;
                    app.y = [app.y zeros(1, tmp)];

                    %dodanie zer do okna
                    app.window = [app.window zeros(1,tmp)];
                else
                    app.update_custom_signal_type(app.FormulaEditField.Value);
                    app.n = 0 : (app.N - 1);
                end

                %okna - obliczanie NPSG
                app.NPSG = (1 / (app.N)) * sum(app.window);
            
            else
                %generowanie sygnalu
                app.n = 0 : (app.N - 1);
                
                app.y = app.A * app.sig_type(arg_real * 2 * pi * app.k * app.n / app.N + arg_real * pi * app.phi / 180);

                %okienkowanie
                update_window_type(app, app.TypoknaDropDown.Value);
                app.y = app.y.* app.window;
                
                %okna - obliczanie NPSG
                app.NPSG = (1 / (app.N)) * sum(app.window);


                %uzupelnianie zerami
                if(app.InterpolacjazeramiCheckBox.Value == true)
                    app.n = 0 : (app.n_interpol - 1);
                    tmp = app.n_interpol - app.N;
                    app.y = [app.y zeros(1, tmp)];
                    
                    %dodanie zer do okna
                    app.window = [app.window zeros(1,tmp)];
                else
                    app.n = 0 : (app.N - 1);
                end


            end


            %rysowanie analizowanego sygnalu
            %czas/probka?
            if(app.SkalaOXButtonGroup.SelectedObject.Tag == "czas")
                x_arg = app.n.* (1 / (app.fp)); % -> [s]
            else
                x_arg = app.n; % n
            end

            if(app.TypsygnauButtonGroup.SelectedObject.Text == "exp")
                %rysowanie sygnalu
                app.plot_type(app.UIAxes, app.y, 'b');
                %rysowanie okna
                hold(app.UIAxes, 'on');
                app.plot_type(app.UIAxes, app.window, 'r');
                hold(app.UIAxes, 'off');
            else
                
                %rysowanie sygnalu
                app.plot_type(app.UIAxes, x_arg, app.y, 'b');

                %rysowanie okna
                hold(app.UIAxes, 'on');
                app.plot_type(app.UIAxes, x_arg, app.window, 'r');
                hold(app.UIAxes, 'off');
            end

            %okna - obliczanie NPSG
            app.NPSG = (1 / (app.N)) * sum(app.window);

            %DFT
            app.DFT = fft(app.y)./ app.N;
            app.DFT = app.DFT / app.NPSG;
            
            %rysowanie
            %lin/log?
            if(string(app.OY_type) == "lin")
                re = real(app.DFT);
                im = imag(app.DFT);
            else
                re = 20 * log10(real(app.DFT));
                im = 20 * log10(imag(app.DFT));
            end
            
            %OX - probka, czas?
            if(app.SkalaOXButtonGroup.SelectedObject.Tag == "czas")
                arg_x = linspace(0, app.fp/2, length(app.n));
            else
                arg_x = app.n;
            end

            %re/im
            app.plot_type(app.UIAxes2, arg_x, re);
            app.plot_type(app.UIAxes3, arg_x, im);

            %amp/faza
            %lin/log?
            if(string(app.OY_type) == "lin")
                arg = abs(app.DFT);
            else
                arg = 20 * log10(app.DFT);
            end
            app.plot_type(app.UIAxes2_2, arg_x, arg); %moduł widma
            DFT_faza = angle(app.DFT); %widmo fazowe
            DFT_faza(abs(app.DFT)<0.0001) = 0; %zerowanie widma fazowego dla zerowych wartości DFT
            app.plot_type(app.UIAxes3_2, arg_x, 180*DFT_faza/pi); %rysokość prążka -> kąt
            
            %płaszczyzna zespolona
            cla(app.UIAxes2_3);
            xlim(app.UIAxes2_3, [-app.A,app.A]);
            ylim(app.UIAxes2_3, [-app.A,app.A]);
            
            %wykreslanie na plaszczyznie zespolonej
            %nie działa z customowym
            if(app.TypsygnauButtonGroup.SelectedObject.Text ~= "Custom:")
                %suma DFT
                suma_DFT = sum(app.DFT);
                dftbuf = app.DFT; %bufor do przekazania do compass(), bez tego error
                compass(app.UIAxes2_3, real([dftbuf suma_DFT]), imag([dftbuf suma_DFT]));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_A(app, app.AEditField.Value);
            update_N(app, app.NSpinner.Value);
            update_phi(app, app.PhiEditField.Value);
            update_k(app, app.kSpinner.Value);
            update_fp(app, app.fpEditField.Value);
            update_signal_type(app, app.TypsygnauButtonGroup.SelectedObject.Text);
            update_plot_type(app, app.PloterButtonGroup.SelectedObject.Text);
            update_OX_type(app, app.SkalaOXButtonGroup.SelectedObject.Tag);
            update_OY_type(app, app.SkalaOYButtonGroup.SelectedObject.Tag);
            update_window_params(app, app.DodatkoweparametryoknaEditField.Value);
            update_window_type(app, app.TypoknaDropDown.Value);
            refresh(app);
        end

        % Value changed function: AEditField
        function AEditFieldValueChanged(app, event)
            update_A(app, app.AEditField.Value);
            refresh(app);
        end

        % Value changed function: NSpinner
        function NSpinnerValueChanged(app, event)
            update_N(app, app.NSpinner.Value);
            refresh(app);
        end

        % Value changed function: PhiEditField
        function PhiEditFieldValueChanged(app, event)
            update_phi(app, app.PhiEditField.Value);
            refresh(app);     
        end

        % Value changed function: kSpinner
        function kSpinnerValueChanged(app, event)
            update_k(app, app.kSpinner.Value);
            refresh(app);
        end

        % Value changed function: fpEditField
        function fpEditFieldValueChanged(app, event)
            update_fp(app, app.fpEditField.Value);
            refresh(app);
        end

        % Selection changed function: TypsygnauButtonGroup
        function TypsygnauButtonGroupSelectionChanged(app, event)
            selectedButton = app.TypsygnauButtonGroup.SelectedObject.Text;
            update_signal_type(app, selectedButton);
            
            refresh(app);
        end

        % Value changed function: FormulaEditField
        function FormulaEditFieldValueChanged(app, event)
            update_custom_signal_type(app, app.FormulaEditField.Value);
            update_window_type(app, app.TypoknaDropDown.Value);
            refresh(app);
        end

        % Selection changed function: PloterButtonGroup
        function PloterButtonGroupSelectionChanged(app, event)
            selectedButton = app.PloterButtonGroup.SelectedObject.Text;
            update_plot_type(app, selectedButton);
            refresh(app);
        end

        % Selection changed function: SkalaOYButtonGroup
        function SkalaOYButtonGroupSelectionChanged(app, event)
            selectedButton = app.SkalaOYButtonGroup.SelectedObject.Tag;
            update_OY_type(app, selectedButton);
            refresh(app);
        end

        % Selection changed function: SkalaOXButtonGroup
        function SkalaOXButtonGroupSelectionChanged(app, event)
            selectedButton = app.SkalaOXButtonGroup.SelectedObject.Tag;
            update_OX_type(app, selectedButton);
            refresh(app);
        end

        % Value changed function: PrbkiinterpolacjiSpinner
        function PrbkiinterpolacjiSpinnerValueChanged(app, event)
            update_n_interpol(app, app.PrbkiinterpolacjiSpinner.Value);
            update_window_type(app, app.TypoknaDropDown.Value);
            refresh(app);
        end

        % Value changed function: InterpolacjazeramiCheckBox
        function InterpolacjazeramiCheckBoxValueChanged(app, event)
            value = app.InterpolacjazeramiCheckBox.Value;
            if(value == true)
                set(app.PrbkiinterpolacjiSpinner, 'enable', 'on');
                set(app.PrbkiinterpolacjiSpinnerLabel, 'enable', 'on');
            else
                set(app.PrbkiinterpolacjiSpinner, 'enable', 'off');
                set(app.PrbkiinterpolacjiSpinnerLabel, 'enable', 'off');
            end
            refresh(app);
        end

        % Value changed function: TypoknaDropDown
        function TypoknaDropDownValueChanged(app, event)
            value = app.TypoknaDropDown.Value;
            update_window_type(app, value);
            refresh(app);
        end

        % Value changed function: DodatkoweparametryoknaEditField
        function DodatkoweparametryoknaEditFieldValueChanged(app, event)
            value = app.DodatkoweparametryoknaEditField.Value;
            update_window_params(app, value);
            update_window_type(app,app.TypoknaDropDown.Value);
            refresh(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {780, 780};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {346, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Analiza DFT';
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Sygnał analizowany')
            xlabel(app.UIAxes, 'Próbka')
            ylabel(app.UIAxes, 'Amplituda')
            zlabel(app.UIAxes, 'Z')
            app.TypsygnauButtonGroup = uibuttongroup(app.LeftPanel);
            app.TypsygnauButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypsygnauButtonGroupSelectionChanged, true);
            app.TypsygnauButtonGroup.Title = 'Typ sygnału';
            app.sinButton = uitogglebutton(app.TypsygnauButtonGroup);
            app.sinButton.Tag = 'sinbutton';
            app.sinButton.Text = 'sin';
            app.sinButton.Value = true;
            app.cosButton = uitogglebutton(app.TypsygnauButtonGroup);
            app.cosButton.Text = 'cos';
            app.expButton = uitogglebutton(app.TypsygnauButtonGroup);
            app.expButton.Text = 'exp';
            app.CustomButton = uitogglebutton(app.TypsygnauButtonGroup);
            app.CustomButton.Text = 'Custom:';
            app.FormulaEditFieldLabel = uilabel(app.TypsygnauButtonGroup);
            app.FormulaEditFieldLabel.Text = 'Formula';
            app.FormulaEditField = uieditfield(app.TypsygnauButtonGroup, 'text');
            app.FormulaEditField.ValueChangedFcn = createCallbackFcn(app, @FormulaEditFieldValueChanged, true);
            app.FormulaEditField.Editable = 'off';
            app.FormulaEditField.Enable = 'off';
            app.FormulaEditField.Value = 'sin(1:10)';
            app.ParametrysygnauPanel = uipanel(app.LeftPanel);
            app.ParametrysygnauPanel.Title = 'Parametry sygnału';
            app.AEditFieldLabel = uilabel(app.ParametrysygnauPanel);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.ParametrysygnauPanel, 'numeric');
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @AEditFieldValueChanged, true);
            app.AEditField.Value = 1;
            app.PhiEditFieldLabel = uilabel(app.ParametrysygnauPanel);
            app.PhiEditFieldLabel.Text = 'Phi';
            app.PhiEditField = uieditfield(app.ParametrysygnauPanel, 'numeric');
            app.PhiEditField.ValueChangedFcn = createCallbackFcn(app, @PhiEditFieldValueChanged, true);
            app.NSpinnerLabel = uilabel(app.ParametrysygnauPanel);
            app.NSpinnerLabel.Text = 'N';
            app.NSpinner = uispinner(app.ParametrysygnauPanel);
            app.NSpinner.ValueChangedFcn = createCallbackFcn(app, @NSpinnerValueChanged, true);
            app.NSpinner.Value = 16;
            app.kSpinnerLabel = uilabel(app.ParametrysygnauPanel);
            app.kSpinnerLabel.Text = 'k';
            app.kSpinner = uispinner(app.ParametrysygnauPanel);
            app.kSpinner.ValueChangedFcn = createCallbackFcn(app, @kSpinnerValueChanged, true);
            app.kSpinner.Value = 2;
            app.fpEditFieldLabel = uilabel(app.ParametrysygnauPanel);
            app.fpEditFieldLabel.Text = 'fp';
            app.fpEditField = uieditfield(app.ParametrysygnauPanel, 'numeric');
            app.fpEditField.ValueChangedFcn = createCallbackFcn(app, @fpEditFieldValueChanged, true);
            app.fpEditField.Value = 1000;
            app.ParametryDFTPanel = uipanel(app.LeftPanel);
            app.ParametryDFTPanel.Title = 'Parametry DFT';
            app.PrbkiinterpolacjiSpinnerLabel = uilabel(app.ParametryDFTPanel);
            app.PrbkiinterpolacjiSpinnerLabel.Enable = 'off';
            app.PrbkiinterpolacjiSpinnerLabel.Text = 'Próbki interpolacji';
            app.PrbkiinterpolacjiSpinner = uispinner(app.ParametryDFTPanel);
            app.PrbkiinterpolacjiSpinner.ValueChangedFcn = createCallbackFcn(app, @PrbkiinterpolacjiSpinnerValueChanged, true);
            app.PrbkiinterpolacjiSpinner.Enable = 'off';
            app.TypoknaDropDownLabel = uilabel(app.ParametryDFTPanel);
            app.TypoknaDropDownLabel.Text = 'Typ okna';
            app.TypoknaDropDown = uidropdown(app.ParametryDFTPanel);
            app.TypoknaDropDown.Items = {'Prostokątne', 'Bartleta (trójkątne)', 'Hanna (Hanninga)', 'Hamminga', 'Blackmana', 'Kaisera', 'Dolpha-Czebyszewa', 'sin^p'};
            app.TypoknaDropDown.ItemsData = {'1', '@triang', '@hann', '@hamming', '@blackman', '@kaiser', '@chebwin', 'sinp'};
            app.TypoknaDropDown.ValueChangedFcn = createCallbackFcn(app, @TypoknaDropDownValueChanged, true);
            app.TypoknaDropDown.Value = '1';
            app.DodatkoweparametryoknaEditFieldLabel = uilabel(app.ParametryDFTPanel);
            app.DodatkoweparametryoknaEditFieldLabel.Text = 'Dodatkowe parametry okna';
            app.DodatkoweparametryoknaEditField = uieditfield(app.ParametryDFTPanel, 'text');
            app.DodatkoweparametryoknaEditField.ValueChangedFcn = createCallbackFcn(app, @DodatkoweparametryoknaEditFieldValueChanged, true);
            app.DodatkoweparametryoknaEditField.Value = '1';
            app.InterpolacjazeramiCheckBox = uicheckbox(app.ParametryDFTPanel);
            app.InterpolacjazeramiCheckBox.ValueChangedFcn = createCallbackFcn(app, @InterpolacjazeramiCheckBoxValueChanged, true);
            app.InterpolacjazeramiCheckBox.Text = 'Interpolacja zerami';
            app.WywietlaniePanel = uipanel(app.LeftPanel);
            app.WywietlaniePanel.Title = 'Wyświetlanie';
            app.SkalaOYButtonGroup = uibuttongroup(app.WywietlaniePanel);
            app.SkalaOYButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SkalaOYButtonGroupSelectionChanged, true);
            app.SkalaOYButtonGroup.Title = 'Skala OY';
            app.LinButton = uitogglebutton(app.SkalaOYButtonGroup);
            app.LinButton.Tag = 'lin';
            app.LinButton.Text = 'Lin';
            app.LinButton.Value = true;
            app.LogdBButton = uitogglebutton(app.SkalaOYButtonGroup);
            app.LogdBButton.Tag = 'log';
            app.LogdBButton.Text = 'Log [dB]';
            app.PloterButtonGroup = uibuttongroup(app.WywietlaniePanel);
            app.PloterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PloterButtonGroupSelectionChanged, true);
            app.PloterButtonGroup.Title = 'Ploter';
            app.plotButton = uitogglebutton(app.PloterButtonGroup);
            app.plotButton.Text = 'plot';
            app.plotButton.Value = true;
            app.stemButton = uitogglebutton(app.PloterButtonGroup);
            app.stemButton.Text = 'stem';
            app.SkalaOXButtonGroup = uibuttongroup(app.WywietlaniePanel);
            app.SkalaOXButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SkalaOXButtonGroupSelectionChanged, true);
            app.SkalaOXButtonGroup.Title = 'Skala OX';
            app.PrbkanButton = uitogglebutton(app.SkalaOXButtonGroup);
            app.PrbkanButton.Tag = 'probka';
            app.PrbkanButton.Text = 'Próbka [n]';
            app.PrbkanButton.Value = true;
            app.CzassHzButton = uitogglebutton(app.SkalaOXButtonGroup);
            app.CzassHzButton.Tag = 'czas';
            app.CzassHzButton.Text = 'Czas [s/Hz]';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.CzrzeczywistaurojonaTab = uitab(app.TabGroup);
            app.CzrzeczywistaurojonaTab.Title = 'Część rzeczywista / urojona';
            app.UIAxes2 = uiaxes(app.CzrzeczywistaurojonaTab);
            title(app.UIAxes2, 'Część rzeczywista')
            xlabel(app.UIAxes2, 'Próbka')
            ylabel(app.UIAxes2, 'Amplituda')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.CzrzeczywistaurojonaTab);
            title(app.UIAxes3, 'Część urojona')
            xlabel(app.UIAxes3, 'Próbka')
            ylabel(app.UIAxes3, 'Amplituda')
            zlabel(app.UIAxes3, 'Z')
            app.WidmoamplitudowefazoweTab = uitab(app.TabGroup);
            app.WidmoamplitudowefazoweTab.Title = 'Widmo amplitudowe / fazowe';
            app.UIAxes2_2 = uiaxes(app.WidmoamplitudowefazoweTab);
            title(app.UIAxes2_2, 'Widmo amplitudowe')
            xlabel(app.UIAxes2_2, 'Próbka')
            ylabel(app.UIAxes2_2, 'Amplituda')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes3_2 = uiaxes(app.WidmoamplitudowefazoweTab);
            title(app.UIAxes3_2, 'Widmo fazowe')
            xlabel(app.UIAxes3_2, 'Próbka')
            ylabel(app.UIAxes3_2, 'Faza')
            zlabel(app.UIAxes3_2, 'Z')
            app.PaszczyznaZespolonaTab = uitab(app.TabGroup);
            app.PaszczyznaZespolonaTab.Title = 'Płaszczyzna Zespolona';
            app.PaszczyznazespolonadlaprkwDFTPanel = uipanel(app.PaszczyznaZespolonaTab);
            app.PaszczyznazespolonadlaprkwDFTPanel.Title = 'Płaszczyzna zespolona dla prążków DFT';
            app.UIAxes2_3 = uiaxes(app.PaszczyznazespolonadlaprkwDFTPanel);
            title(app.UIAxes2_3, 'Z')
            xlabel(app.UIAxes2_3, 'Re')
            ylabel(app.UIAxes2_3, 'Im')
            zlabel(app.UIAxes2_3, 'Z')
            app.PorwnanieokienczasowychPanel = uipanel(app.RightPanel);
            app.PorwnanieokienczasowychPanel.Title = 'Porównanie okien czasowych';
            app.PorwnajsygnadlawybranychokienczasowychButton = uibutton(app.PorwnanieokienczasowychPanel, 'push');
            app.PorwnajsygnadlawybranychokienczasowychButton.Text = 'Porównaj sygnał dla wybranych okien czasowych';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end